classdef newAcquisition_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AdderrorsButton          matlab.ui.control.Button
        DATAVIEWERANALYZERLabel  matlab.ui.control.Label
        AddmarkerButton          matlab.ui.control.Button
        ScaleSpinner             matlab.ui.control.Spinner
        ScaleVLabel              matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        RestartButton            matlab.ui.control.Button
        EXITButton               matlab.ui.control.Button
        MeanValueLabel           matlab.ui.control.Label
        NASAsurveysmeanLabel     matlab.ui.control.Label
        ShowdataacquiredButton   matlab.ui.control.Button
        P4CheckBox               matlab.ui.control.CheckBox
        P3CheckBox               matlab.ui.control.CheckBox
        PzCheckBox               matlab.ui.control.CheckBox
        AF8CheckBox              matlab.ui.control.CheckBox
        AF7CheckBox              matlab.ui.control.CheckBox
        AF4CheckBox              matlab.ui.control.CheckBox
        AF3CheckBox              matlab.ui.control.CheckBox
        AFzCheckBox              matlab.ui.control.CheckBox
        Image                    matlab.ui.control.Image
        TaskDropDown             matlab.ui.control.DropDown
        TaskDropDownLabel        matlab.ui.control.Label
        NchannelsSpinner         matlab.ui.control.Spinner
        NchannelsSpinnerLabel    matlab.ui.control.Label
        FsEditField              matlab.ui.control.NumericEditField
        FsEditFieldLabel         matlab.ui.control.Label
        AddsubjectButton         matlab.ui.control.Button
        SubjectInformationPanel  matlab.ui.container.Panel
        AgeSpinner               matlab.ui.control.Spinner
        AgeSpinnerLabel          matlab.ui.control.Label
        DominantHandButtonGroup  matlab.ui.container.ButtonGroup
        LeftButton               matlab.ui.control.RadioButton
        RightButton              matlab.ui.control.RadioButton
        SexButtonGroup           matlab.ui.container.ButtonGroup
        FButton                  matlab.ui.control.RadioButton
        MButton                  matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function updateErrors(app, nerr)
            global task;
            global l;
            writelines(strcat("Errors = ", string(nerr)), strcat('../EEG/',task,'/S',string(l),'/errorsMade_S', string(l),'_',task,'_.txt'));
            msgbox(strcat(task, " error of subject S", string(l), " entered!"))

        end

        function updateSurvey(app, me, ph, te, pe, ef, fr)
            global task;
            global l;
            writecell({'Mental', 'Physical', 'Temporal', 'Performance', 'Effort', 'Frustration'}, strcat('../EEG/',task,'/S',string(l),'/NASA_S', string(l),'_',task,'_.xlsx'), 'WriteMode','overwrite');

            writecell({me, ph, te, pe, ef, fr}, strcat('../EEG/',task,'/S',string(l),'/NASA_S', string(l),'_',task,'_.xlsx'), 'WriteMode','append');

            app.MeanValueLabel.Text = string(round(mean([me, ph, te, pe, ef, fr]), 2));
            waitfor(msgbox(strcat(task, " survey of subject S", string(l), " updated!")))

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TaskDropDown.Items = {'--Select task--', 'restEO', 'restEC', 'STmotor', 'STfree', 'STcounting7', 'STcounting1', 'DTfree', 'DTcounting7', 'DTcounting1'};
            global subjects
            subjects = readtable('../EEG/subjects_info.xlsx');
            % app.StopButton.Enable = "off";
            app.PlotButton.Enable = "off";
            app.AddsubjectButton.Enable = "on";
            app.AddmarkerButton.Enable = 'off';
            app.AdderrorsButton.Enable = 'off';
            app.RestartButton.Enable = 'off';
            app.UIAxes.XLim = [0 10];
        end

        % Button pushed function: ShowdataacquiredButton
        function ShowdataacquiredButtonPushed(app, event)
            old_acquisition_update;
            delete(app);
        end

        % Button pushed function: AddsubjectButton
        function AddsubjectButtonPushed(app, event)
            global subjects
            global l
            l = size(subjects, 1) + 1;
            t = {l, app.AgeSpinner.Value, app.SexButtonGroup.SelectedObject.Text, app.DominantHandButtonGroup.SelectedObject.Text};
            writecell(t, '../EEG/subjects_info.xlsx', 'WriteMode','append');

            msgbox(strcat("Subject S", string(l), " entered"))
            app.PlotButton.Enable = "on";
            app.AddsubjectButton.Enable = "off";
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global j;
            global add;
            global add_err;
            global counter;
            global task;
            global l;
            global stop
            stop = false;
            if strcmp(app.PlotButton.Icon, 'stopdef.png')
                app.PlotButton.Icon = 'startic.png';
                stop = true;
                app.AddmarkerButton.Enable = 'off';
                app.AdderrorsButton.Enable = 'off';
                app.RestartButton.Enable = 'on';
                survey(app, 1, 1, 1, 1, 1, 1, task)

            else


                cla(app.UIAxes);
                
                counter = 0;
                add = false;
                add_err = false;

                scale = app.ScaleSpinner.Value / 100;
                task = app.TaskDropDown.Value;
                if contains(task, 'counting')
                    app.AdderrorsButton.Enable = 'on';
                end
                app.RestartButton.Enable = 'off';
                app.AddmarkerButton.Enable = 'on';
                nChan = app.NchannelsSpinner.Value;
                EEG = pop_loadset('EEG_new_Stest.set', '../dataset_folder/'); %no NaN
                
                limY = [-500, 4000];
                app.UIAxes.YLim = [limY(1) - scale * 100 limY(2) + scale * 100];
                EEG.srate = app.FsEditField.Value; %ok

                x = EEG.times;
                x = (x - x(1));
                y = EEG.data;
                j = 1;

                rm = zeros(1, 8);
                if app.AFzCheckBox.Value == 0
                    rm(1) = 1;
                    first = nan;
                else
                    first = y(1, j);
                end

                if app.AF3CheckBox.Value == 0
                    rm(2) = 2;
                    second = nan;
                else
                    second = y(2, j);
                end

                if app.AF4CheckBox.Value == 0
                    rm(3) = 3;
                    third = nan;
                else
                    third = y(3, j);
                end

                if app.AF7CheckBox.Value == 0
                    rm(4) = 4;
                    fourth = nan;
                else
                    fourth = y(4, j);
                end

                if app.AF8CheckBox.Value == 0
                    rm(5) = 5;
                    fifth = nan;
                else
                    fifth = y(5, j);
                end

                if app.PzCheckBox.Value == 0
                    rm(6) = 6;
                    sixth = nan;
                else
                    sixth = y(6, j);
                end

                if app.P3CheckBox.Value == 0
                    rm(7) = 7;
                    seventh = nan;
                else
                    seventh = y(7, j);
                end

                if app.P4CheckBox.Value == 0
                    rm(8) = 8;
                    eighth = nan;
                else
                    eighth = y(8, j);
                end


                check = find(rm == 0);
                

                for i = 1 : nChan
                    y(check(i), :) = y(check(i), :)*(scale+3) + app.UIAxes.YTick(9 - check(i));
                end


                %plot raw data
                app.UIAxes.Title.String = strcat("EEG subject S", string(l), " data");
                mkdir(strcat('../EEG/',task,'/'), strcat('S', string(l)));
                writecell({'Timestamp', 'AFz', 'AF3', 'AF4', 'AF7', 'AF8', 'Pz', 'P3', 'P4'}, strcat('../EEG/',task,'/S',string(l),'/EEG_',task,'_S', string(l),'_added.xlsx'), 'WriteMode','overwrite');
                writecell({'Timestamp', 'Marker'}, strcat('../EEG/',task,'/S',string(l),'/EEG_',task,'_S', string(l),'_added_mrk.xlsx'), 'WriteMode','overwrite');
                writecell({'Timestamp', 'Marker Errors'}, strcat('../EEG/',task,'/S',string(l),'/EEG_',task,'_S', string(l),'_added_mrkerr.xlsx'), 'WriteMode','overwrite');

                app.PlotButton.Icon = 'stopdef.png';

                while(x(j) <= x(length(x)) && stop == false)

                    if(x(j) >= app.UIAxes.XLim(2))
                        app.UIAxes.XLim = [x(j) x(j) + 10];
                    end
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, x(1:j), y(check, 1:j), "Color", 'red');
                    if (add == true)
                        xline(app.UIAxes, x(j), 'Color', "#77AC30")
                        writecell({x(j), counter}, strcat('../EEG/',task,'/S',string(l),'/EEG_',task,'_S', string(l),'_added_mrk.xlsx'), 'WriteMode','append');
                        add = false;
                    end
                    if (add_err == true)
                        xline(app.UIAxes, x(j), 'Color', 'magenta')
                        writecell({x(j), counter}, strcat('../EEG/',task,'/S',string(l),'/EEG_',task,'_S', string(l),'_added_mrkerr.xlsx'), 'WriteMode','append');
                        add_err = false;
                    end
                    hold(app.UIAxes, "off")
                    writecell({x(j), first, second, third, fourth, fifth, sixth, seventh, eighth}, strcat('../EEG/',task,'/S',string(l),'/EEG_',task,'_S', string(l),'_added.xlsx'), 'WriteMode','append');
                    j = j + 10;
                    drawnow;

                end
            end

        end

        % Button pushed function: AddmarkerButton
        function AddmarkerButtonPushed(app, event)
            global add
            global counter
            add = true;
            counter = counter + 1;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.AddsubjectButton.Enable = 'on';
            app.ScaleSpinner.Value = 100;
            app.PlotButton.Enable = 'off';
            app.PlotButton.Icon = 'staric.png';
            app.AFzCheckBox.Value = 0;
            app.AF3CheckBox.Value = 0;
            app.AF4CheckBox.Value = 0;
            app.AF7CheckBox.Value = 0;
            app.AF8CheckBox.Value = 0;
            app.PzCheckBox.Value = 0;
            app.P3CheckBox.Value = 0;
            app.P4CheckBox.Value = 0;
            app.NchannelsSpinner.Value = 8;
            app.TaskDropDown.Value = '--Select task--';
            app.FsEditField.Value = 125;
            app.MeanValueLabel.Text = '...';
            app.AgeSpinner.Value = 18;
            app.MButton.Value = true;
            app.RightButton.Value = true;
            cla(app.UIAxes);
            app.UIAxes.XLim = [0 10];
        end

        % Button pushed function: AdderrorsButton
        function AdderrorsButtonPushed(app, event)
            global add_err
            global counter
            add_err = true;
            counter = counter + 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-500 4000];
            app.UIAxes.YTick = [100 600 1100 1600 2100 2600 3100 3600];
            app.UIAxes.YTickLabel = {'P4'; 'P3'; 'Pz'; 'AF8'; 'AF7'; 'AF4'; 'AF3'; 'AFz'};
            app.Panel = uipanel(app.UIFigure);
            app.SubjectInformationPanel = uipanel(app.Panel);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.SexButtonGroup = uibuttongroup(app.SubjectInformationPanel);
            app.SexButtonGroup.Title = 'Sex';
            app.MButton = uiradiobutton(app.SexButtonGroup);
            app.MButton.Text = 'M';
            app.MButton.Value = true;
            app.FButton = uiradiobutton(app.SexButtonGroup);
            app.FButton.Text = 'F';
            app.DominantHandButtonGroup = uibuttongroup(app.SubjectInformationPanel);
            app.DominantHandButtonGroup.Title = 'Dominant Hand';
            app.RightButton = uiradiobutton(app.DominantHandButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            app.LeftButton = uiradiobutton(app.DominantHandButtonGroup);
            app.LeftButton.Text = 'Left';
            app.AgeSpinnerLabel = uilabel(app.SubjectInformationPanel);
            app.AgeSpinnerLabel.Text = 'Age';
            app.AgeSpinner = uispinner(app.SubjectInformationPanel);
            app.AgeSpinner.Limits = [1 130];
            app.AgeSpinner.Value = 18;
            app.AddsubjectButton = uibutton(app.Panel, 'push');
            app.AddsubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddsubjectButtonPushed, true);
            app.AddsubjectButton.Text = 'Add subject';
            app.FsEditFieldLabel = uilabel(app.Panel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Panel, 'numeric');
            app.FsEditField.Value = 125;
            app.NchannelsSpinnerLabel = uilabel(app.Panel);
            app.NchannelsSpinnerLabel.Text = 'N. channels';
            app.NchannelsSpinner = uispinner(app.Panel);
            app.NchannelsSpinner.Limits = [1 8];
            app.NchannelsSpinner.Value = 8;
            app.TaskDropDownLabel = uilabel(app.Panel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.Panel);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'Immagine12.png';
            app.AFzCheckBox = uicheckbox(app.Panel);
            app.AFzCheckBox.Text = 'AFz';
            app.AF3CheckBox = uicheckbox(app.Panel);
            app.AF3CheckBox.Text = 'AF3';
            app.AF4CheckBox = uicheckbox(app.Panel);
            app.AF4CheckBox.Text = 'AF4';
            app.AF7CheckBox = uicheckbox(app.Panel);
            app.AF7CheckBox.Text = 'AF7';
            app.AF8CheckBox = uicheckbox(app.Panel);
            app.AF8CheckBox.Text = 'AF8';
            app.PzCheckBox = uicheckbox(app.Panel);
            app.PzCheckBox.Text = 'Pz';
            app.P3CheckBox = uicheckbox(app.Panel);
            app.P3CheckBox.Text = 'P3';
            app.P4CheckBox = uicheckbox(app.Panel);
            app.P4CheckBox.Text = 'P4';
            app.ShowdataacquiredButton = uibutton(app.Panel, 'push');
            app.ShowdataacquiredButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdataacquiredButtonPushed, true);
            app.ShowdataacquiredButton.Icon = 'show.png';
            app.ShowdataacquiredButton.Text = 'Show data acquired';
            app.NASAsurveysmeanLabel = uilabel(app.Panel);
            app.NASAsurveysmeanLabel.Text = 'NASA survey''s mean:';
            app.MeanValueLabel = uilabel(app.Panel);
            app.MeanValueLabel.Text = '...';
            app.EXITButton = uibutton(app.Panel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Icon = fullfile(pathToMLAPP, 'exitic.png');
            app.EXITButton.Text = '';
            app.RestartButton = uibutton(app.Panel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.RestartButton.Text = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'startic.png');
            app.PlotButton.Text = '';
            app.ScaleVLabel = uilabel(app.UIFigure);
            app.ScaleVLabel.Text = 'Scale (μV)';
            app.ScaleSpinner = uispinner(app.UIFigure);
            app.ScaleSpinner.Step = 50;
            app.ScaleSpinner.Limits = [50 500];
            app.ScaleSpinner.Value = 100;
            app.AddmarkerButton = uibutton(app.UIFigure, 'push');
            app.AddmarkerButton.ButtonPushedFcn = createCallbackFcn(app, @AddmarkerButtonPushed, true);
            app.AddmarkerButton.Icon = fullfile(pathToMLAPP, 'markericon.png');
            app.AddmarkerButton.Text = '';
            app.DATAVIEWERANALYZERLabel = uilabel(app.UIFigure);
            app.DATAVIEWERANALYZERLabel.Text = 'DATA VIEWER & ANALYZER';
            app.AdderrorsButton = uibutton(app.UIFigure, 'push');
            app.AdderrorsButton.ButtonPushedFcn = createCallbackFcn(app, @AdderrorsButtonPushed, true);
            app.AdderrorsButton.Icon = 'erroricongnew.png';
            app.AdderrorsButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newAcquisition_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end