classdef old_acquisition_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DATAVIEWERANALYZERLabel  matlab.ui.control.Label
        ScaleSpinner             matlab.ui.control.Spinner
        ScaleVSpinnerLabel       matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        PlotButton               matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        New                      matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        EXITButton               matlab.ui.control.Button
        AF4CheckBox              matlab.ui.control.CheckBox
        AF8CheckBox              matlab.ui.control.CheckBox
        P4CheckBox               matlab.ui.control.CheckBox
        P3CheckBox               matlab.ui.control.CheckBox
        PzCheckBox               matlab.ui.control.CheckBox
        AF7CheckBox              matlab.ui.control.CheckBox
        AF3CheckBox              matlab.ui.control.CheckBox
        AFzCheckBox              matlab.ui.control.CheckBox
        Image_2                  matlab.ui.control.Image
        SubjectDropDown          matlab.ui.control.DropDown
        SubjectDropDownLabel     matlab.ui.control.Label
        NchannelsSpinner         matlab.ui.control.Spinner
        NchannelsSpinnerLabel    matlab.ui.control.Label
        TaskDropDownLabel        matlab.ui.control.Label
        TaskDropDown             matlab.ui.control.DropDown
        LowfcutEditField         matlab.ui.control.NumericEditField
        LowfcutEditFieldLabel    matlab.ui.control.Label
        HighfcutEditField        matlab.ui.control.NumericEditField
        HighfcutEditFieldLabel   matlab.ui.control.Label
        FilterSwitch             matlab.ui.control.Switch
        FilterSwitchLabel        matlab.ui.control.Label
        FsEditField              matlab.ui.control.NumericEditField
        FsEditFieldLabel         matlab.ui.control.Label
        SubPanel                 matlab.ui.container.Panel
        HandVal                  matlab.ui.control.Label
        SexVal                   matlab.ui.control.Label
        AgeVal                   matlab.ui.control.Label
        DominanthandLabel        matlab.ui.control.Label
        SexLabel                 matlab.ui.control.Label
        AgeLabel                 matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global subjects
            global counter
            counter = 0;
            subjects = readtable('../EEG/subjects_info.xlsx');
            app.SubjectDropDown.Items = string(subjects.ID');
            app.TaskDropDown.Items = {'--Select task--', 'Motor single task', 'Cognitive single task', 'Motor-cognitive dual task'};
            app.StopButton.Enable = "off";
            app.PlotButton.Enable = "on";
            app.ClearButton.Enable = 'off';
            app.UIAxes.XLim = [0 10];


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Load file
            global stop
            global counter
            global subjects
            global j
            stop = false;
            
            if strcmp(app.PlotButton.Icon, 'pauseicon.png')
                stop = true;
                app.PlotButton.Icon = 'startic.png';
            else
                app.PlotButton.Icon = 'pauseicon.png';

                limY = [-500, 4000];
                selected = app.SubjectDropDown.Value;
                app.SubPanel.Title = strcat("Subject S", selected);
                app.AgeVal.Text = string(subjects.Age(str2num(selected)));
                app.SexVal.Text = string(subjects.Sex(str2num(selected)));
                app.HandVal.Text = subjects.Hand(str2num(selected));

                scale = app.ScaleSpinner.Value / 100;
                task = app.TaskDropDown.Value;
                s = app.SubjectDropDown.Value;
                app.StopButton.Enable = "on";
                nChan = app.NchannelsSpinner.Value;
               
                if (strcmp(task, 'Motor single task'))
                    task = 'STmotor';
                end
                if (strcmp(task, 'Cognitive single task'))
                    task = 'STcounting1';
                end
                if(strcmp(task, 'Motor-cognitive dual task'))
                    task = 'DTcounting1';
                end
            
                EEG = pop_loadset(strcat('EEG_', task,'_S', s ,'.set'), '../dataset_folder/'); %no NaN

                EEG.srate = app.FsEditField.Value; %ok

                rm = zeros(1, 8);
                if app.AFzCheckBox.Value == 0
                    rm(1) = 1;
                end

                if app.AF3CheckBox.Value == 0
                    rm(2) = 2;
                end

                if app.AF4CheckBox.Value == 0
                    rm(3) = 3;
                end

                if app.AF7CheckBox.Value == 0
                    rm(4) = 4;
                end

                if app.AF8CheckBox.Value == 0
                    rm(5) = 5;
                end

                if app.PzCheckBox.Value == 0
                    rm(6) = 6;
                end

                if app.P3CheckBox.Value == 0
                    rm(7) = 7;
                end

                if app.P4CheckBox.Value == 0
                    rm(8) = 8;
                end

                f = string(app.FilterSwitch.Value);
                x = EEG.times;
                x = (x - x(1));
                y = EEG.data;
                check = find(rm == 0);                    

                
                if f == "On"
                    app.UIAxes.Title.String = "Filtered EEG data";
                    %plot filtered data
                    fs = EEG.srate;
                    fn = fs/2;
                    Rp = 3;
                    Rs = 20;
                    high = app.HighfcutEditField.Value;
                    low = app.LowfcutEditField.Value;
                    y = filter_data(high, low, fn, Rp, Rs, y');
                else
                    %raw data
                    app.UIAxes.Title.String = "Raw EEG data";
                end
               
                for i = 1 : nChan
                    y(check(i), :) = y(check(i), :)*(scale+3) + app.UIAxes.YTick(9 - check(i));
                end                
                if counter == 0
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [0 10];
                    counter = counter + 1;
                    j = 1;
                end
                while(j <= length(x) && stop == false)
                    if(x(j) >= app.UIAxes.XLim(2))
                        app.UIAxes.XLim = [x(j) x(j) + 10];
                                
                    end
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, x(1:j), y(check, 1:j), "Color", 'red');
                    hold(app.UIAxes, "off")
                    j = j + 10;
                    drawnow;
                end
                if stop == false
                    stop = true;
                    counter = 0;
                    app.PlotButton.Icon = 'startic.png';
                    app.PlotButton.Enable = "on";
                    app.StopButton.Enable = "off";
                    app.ClearButton.Enable = 'on';
                end
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop
            global counter
            stop = true;
            counter = 0;
            app.PlotButton.Icon = 'startic.png';
            app.PlotButton.Enable = "on";
            app.StopButton.Enable = "off";
            app.ClearButton.Enable = 'on';
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
           delete(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ScaleSpinner.Value = 100;
            app.StopButton.Enable = 'off';
            app.PlotButton.Enable = 'on';
            app.PlotButton.Icon = 'staric.png';
            app.AFzCheckBox.Value = 0;
            app.AF3CheckBox.Value = 0;
            app.AF4CheckBox.Value = 0;
            app.AF7CheckBox.Value = 0;
            app.AF8CheckBox.Value = 0;
            app.PzCheckBox.Value = 0;
            app.P3CheckBox.Value = 0;
            app.P4CheckBox.Value = 0;
            app.SubjectDropDown.Value = '1';
            app.NchannelsSpinner.Value = 8;
            app.TaskDropDown.Value = '--Select task--';
            app.LowfcutEditField.Value = 30;
            app.HighfcutEditField.Value = 2;
            app.FilterSwitch.Value = 'Off';
            app.FsEditField.Value = 125;
            app.SubPanel.Title = '';
            app.HandVal.Text = '...';
            app.SexVal.Text = '...';
            app.AgeVal.Text = '...';
            cla(app.UIAxes);
            app.UIAxes.XLim = [0 10];
        end

        % Button pushed function: New
        function NewButtonPushed(app, event)
            newAcquisition_update;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-500 4000];
            app.UIAxes.YTick = [100 600 1100 1600 2100 2600 3100 3600];
            app.UIAxes.YTickLabel = {'P4'; 'P3'; 'Pz'; 'AF8'; 'AF7'; 'AF4'; 'AF3'; 'AFz'};
            app.Panel = uipanel(app.UIFigure);
            app.SubPanel = uipanel(app.Panel);
            app.AgeLabel = uilabel(app.SubPanel);
            app.AgeLabel.Text = 'Age:';
            app.SexLabel = uilabel(app.SubPanel);
            app.SexLabel.Text = 'Sex:';
            app.DominanthandLabel = uilabel(app.SubPanel);
            app.DominanthandLabel.Text = 'Dominant hand:';
            app.AgeVal = uilabel(app.SubPanel);
            app.AgeVal.Text = '...';
            app.SexVal = uilabel(app.SubPanel);
            app.SexVal.Text = '...';
            app.HandVal = uilabel(app.SubPanel);
            app.HandVal.Text = '...';
            app.FsEditFieldLabel = uilabel(app.Panel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Panel, 'numeric');
            app.FsEditField.Value = 125;
            app.FilterSwitchLabel = uilabel(app.Panel);
            app.FilterSwitchLabel.Text = 'Filter';
            app.FilterSwitch = uiswitch(app.Panel, 'slider');
            app.HighfcutEditFieldLabel = uilabel(app.Panel);
            app.HighfcutEditFieldLabel.Text = 'High fcut';
            app.HighfcutEditField = uieditfield(app.Panel, 'numeric');
            app.HighfcutEditField.Limits = [1 Inf];
            app.HighfcutEditField.Value = 2;
            app.LowfcutEditFieldLabel = uilabel(app.Panel);
            app.LowfcutEditFieldLabel.Text = 'Low fcut';
            app.LowfcutEditField = uieditfield(app.Panel, 'numeric');
            app.LowfcutEditField.Limits = [1 Inf];
            app.LowfcutEditField.Value = 30;
            app.TaskDropDown = uidropdown(app.Panel);
            app.TaskDropDownLabel = uilabel(app.Panel);
            app.TaskDropDownLabel.Text = 'Task';
            app.NchannelsSpinnerLabel = uilabel(app.Panel);
            app.NchannelsSpinnerLabel.Text = 'N. channels';
            app.NchannelsSpinner = uispinner(app.Panel);
            app.NchannelsSpinner.Limits = [1 8];
            app.NchannelsSpinner.Value = 8;
            app.SubjectDropDownLabel = uilabel(app.Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Panel);
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = 'Immagine12.png';
            app.AFzCheckBox = uicheckbox(app.Panel);
            app.AFzCheckBox.Text = 'AFz';
            app.AF3CheckBox = uicheckbox(app.Panel);
            app.AF3CheckBox.Text = 'AF3';
            app.AF7CheckBox = uicheckbox(app.Panel);
            app.AF7CheckBox.Text = 'AF7';
            app.PzCheckBox = uicheckbox(app.Panel);
            app.PzCheckBox.Text = 'Pz';
            app.P3CheckBox = uicheckbox(app.Panel);
            app.P3CheckBox.Text = 'P3';
            app.P4CheckBox = uicheckbox(app.Panel);
            app.P4CheckBox.Text = 'P4';
            app.AF8CheckBox = uicheckbox(app.Panel);
            app.AF8CheckBox.Text = 'AF8';
            app.AF4CheckBox = uicheckbox(app.Panel);
            app.AF4CheckBox.Text = 'AF4';
            app.EXITButton = uibutton(app.Panel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Icon = fullfile(pathToMLAPP, 'exitic.png');
            app.EXITButton.Text = '';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.ClearButton.Text = '';
            app.New = uibutton(app.Panel, 'push');
            app.New.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.New.Icon = fullfile(pathToMLAPP, 'plus_ic.png');
            app.New.Text = 'New acquisition';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'startic.png');
            app.PlotButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'stopdef.png');
            app.StopButton.Text = '';
            app.ScaleVSpinnerLabel = uilabel(app.UIFigure);
            app.ScaleVSpinnerLabel.Text = 'Scale (μV)';
            app.ScaleSpinner = uispinner(app.UIFigure);
            app.ScaleSpinner.Step = 50;
            app.ScaleSpinner.Limits = [50 500];
            app.ScaleSpinner.Value = 100;
            app.DATAVIEWERANALYZERLabel = uilabel(app.UIFigure);
            app.DATAVIEWERANALYZERLabel.Text = 'DATA VIEWER & ANALYZER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = old_acquisition_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end