classdef survey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NASASURVEYLabel          matlab.ui.control.Label
        FrustrationSpinner       matlab.ui.control.Spinner
        FrustrationSpinnerLabel  matlab.ui.control.Label
        EffortSpinner            matlab.ui.control.Spinner
        EffortSpinnerLabel       matlab.ui.control.Label
        PerformanceSpinner       matlab.ui.control.Spinner
        PerformanceSpinnerLabel  matlab.ui.control.Label
        TemporalSpinner          matlab.ui.control.Spinner
        TemporalSpinnerLabel     matlab.ui.control.Label
        PhysicalSpinner          matlab.ui.control.Spinner
        PhysicalSpinnerLabel     matlab.ui.control.Label
        MentalSpinner            matlab.ui.control.Spinner
        MentalSpinnerLabel       matlab.ui.control.Label
        UpdatesurveyButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
        taskDone % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, newapp, me, ph, te, pe, ef, fr, task)
            app.MentalSpinner.Value = me;
            app.PhysicalSpinner.Value = ph;
            app.TemporalSpinner.Value = te;
            app.PerformanceSpinner.Value = pe;
            app.EffortSpinner.Value = ef;
            app.FrustrationSpinner.Value = fr;
            app.MainApp = newapp;
            app.taskDone = task;
        end

        % Button pushed function: UpdatesurveyButton
        function UpdatesurveyButtonPushed(app, event)
            updateSurvey(app.MainApp, app.MentalSpinner.Value, app.PhysicalSpinner.Value, app.TemporalSpinner.Value, app.PerformanceSpinner.Value, app.EffortSpinner.Value, app.FrustrationSpinner.Value)
            if contains(app.taskDone, 'counting')
                counting_errors(app.MainApp, 0)
                delete(app)
            else
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UpdatesurveyButton = uibutton(app.UIFigure, 'push');
            app.UpdatesurveyButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatesurveyButtonPushed, true);
            app.UpdatesurveyButton.Icon = 'done.png';
            app.UpdatesurveyButton.Text = '';
            app.MentalSpinnerLabel = uilabel(app.UIFigure);
            app.MentalSpinnerLabel.Text = 'Mental';
            app.MentalSpinner = uispinner(app.UIFigure);
            app.MentalSpinner.Limits = [1 20];
            app.MentalSpinner.Value = 1;
            app.PhysicalSpinnerLabel = uilabel(app.UIFigure);
            app.PhysicalSpinnerLabel.Text = 'Physical';
            app.PhysicalSpinner = uispinner(app.UIFigure);
            app.PhysicalSpinner.Limits = [1 20];
            app.PhysicalSpinner.Value = 1;
            app.TemporalSpinnerLabel = uilabel(app.UIFigure);
            app.TemporalSpinnerLabel.Text = 'Temporal';
            app.TemporalSpinner = uispinner(app.UIFigure);
            app.TemporalSpinner.Limits = [1 20];
            app.TemporalSpinner.Value = 1;
            app.PerformanceSpinnerLabel = uilabel(app.UIFigure);
            app.PerformanceSpinnerLabel.Text = 'Performance';
            app.PerformanceSpinner = uispinner(app.UIFigure);
            app.PerformanceSpinner.Limits = [1 20];
            app.PerformanceSpinner.Value = 1;
            app.EffortSpinnerLabel = uilabel(app.UIFigure);
            app.EffortSpinnerLabel.Text = 'Effort';
            app.EffortSpinner = uispinner(app.UIFigure);
            app.EffortSpinner.Limits = [1 20];
            app.EffortSpinner.Value = 1;
            app.FrustrationSpinnerLabel = uilabel(app.UIFigure);
            app.FrustrationSpinnerLabel.Text = 'Frustration';
            app.FrustrationSpinner = uispinner(app.UIFigure);
            app.FrustrationSpinner.Limits = [1 20];
            app.FrustrationSpinner.Value = 1;
            app.NASASURVEYLabel = uilabel(app.UIFigure);
            app.NASASURVEYLabel.Text = 'NASA SURVEY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = survey(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end